classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        WedgesEditFieldLabel       matlab.ui.control.Label
        WedgesEditField            matlab.ui.control.EditField
        EnterButton                matlab.ui.control.Button
        ConstraintsEditFieldLabel  matlab.ui.control.Label
        ConstraintsEditField       matlab.ui.control.EditField
        StrategyEditFieldLabel     matlab.ui.control.Label
        StrategyEditField          matlab.ui.control.EditField
        Lamp                       matlab.ui.control.Lamp
        Lamp2                      matlab.ui.control.Lamp
        Lamp2_2                    matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        
       wedgeData = 0;
       constraintsData = 0;
       strategyData = 0;
    end
    
    methods (Access = private)
        
        function output = readInFile(app,filepath,index)
            file = fopen(filepath,'r');
            fileData = {};
            header = fgetl(file);
            if index == 1
                delim = ';';
            else
                delim = ' ';
            end
            header_p = strsplit(header,delim)
            temp_output = cell(1,2*length(header_p));
            temp_output(1:2:end) = header_p;
            output = struct(temp_output{1:end});
            fields = fieldnames(output);
            while ~feof(file)
                curr_line = fgetl(file);
                curr_line_p = strsplit(curr_line,delim);
                
                for i = 1:length(curr_line_p)
                    if isempty(output.(fields{i}))
                        output.(fields{i}) = curr_line_p(i);
                    else
                        output.(fields{i})(end+1) = curr_line_p(i);
                    end
                end
            end
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            x=2;
            app.Lamp.Color = [ 1 0 0];
            app.Lamp2.Color = [ 1 0 0];
            app.Lamp2_2.Color = [1 0 0 ];
        end

        % Value changed function: WedgesEditField
        function WedgesEditFieldValueChanged(app, event)
            myWedge = app.WedgesEditField.Value;
            
        end

        % Value changed function: ConstraintsEditField
        function ConstraintsEditFieldValueChanged(app, event)
            myConstraint = app.ConstraintsEditField.Value;
            
        end

        % Value changed function: StrategyEditField
        function StrategyEditFieldValueChanged(app, event)
            myStrategy = app.StrategyEditField.Value;
            
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            fail_p=  1;
            try
                fpaths = {app.WedgesEditField.Value;app.ConstraintsEditField.Value;app.StrategyEditField.Value};
                fail_p=  1;
                app.wedgeData = app.readInFile(fpaths{1},1);
                fail_p=  2;
                app.constraintsData = app.readInFile(fpaths{2},2);
                fail_p=  3;
                app.strategyData = app.readInFile(fpaths{3},3);
                app.Lamp.Color = [ 0 1 0];
                app.Lamp2.Color = [ 0 1 0];
                app.Lamp2_2.Color = [0 1 0 ];
                success = (msgbox('Success: All files read in','Success','modal'));
            catch
                error = msgbox(sprintf('Invalid Filename: filepath %d',fail_p), 'Error','error');
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.WedgesEditFieldLabel = uilabel(app.UIFigure);
            app.WedgesEditFieldLabel.Text = 'Wedges';
            app.WedgesEditField = uieditfield(app.UIFigure, 'text');
            app.WedgesEditField.ValueChangedFcn = createCallbackFcn(app, @WedgesEditFieldValueChanged, true);
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ConstraintsEditFieldLabel = uilabel(app.UIFigure);
            app.ConstraintsEditFieldLabel.Text = 'Constraints';
            app.ConstraintsEditField = uieditfield(app.UIFigure, 'text');
            app.ConstraintsEditField.ValueChangedFcn = createCallbackFcn(app, @ConstraintsEditFieldValueChanged, true);
            app.StrategyEditFieldLabel = uilabel(app.UIFigure);
            app.StrategyEditFieldLabel.Text = 'Strategy';
            app.StrategyEditField = uieditfield(app.UIFigure, 'text');
            app.StrategyEditField.ValueChangedFcn = createCallbackFcn(app, @StrategyEditFieldValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.Lamp2_2 = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end